classdef biocad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FoldernameLabel                 matlab.ui.control.Label
        CopyrightsLabel                 matlab.ui.control.Label
        MainTabGroup                    matlab.ui.container.TabGroup
        BiocadTab                       matlab.ui.container.Tab
        LoadingPanel                    matlab.ui.container.Panel
        LoadingLabel                    matlab.ui.control.Label
        StoreDirectoryButtonGroup       matlab.ui.container.ButtonGroup
        FilePathLabel                   matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        AboutButton                     matlab.ui.control.Button
        BiocadLogo                      matlab.ui.control.Image
        ToolsPanel_Menu                 matlab.ui.container.Panel
        FileTab                         matlab.ui.container.Tab
        MicroSystemPanel                matlab.ui.container.Panel
        FileInfoLabel_MicroSystem       matlab.ui.control.Label
        ElectrodeRolePanel              matlab.ui.container.Panel
        CurrentNegativeDropDown_E       matlab.ui.control.DropDown
        CurrentNegativeDropDownLabel_E  matlab.ui.control.Label
        CurrentPositiveDropDown_E       matlab.ui.control.DropDown
        CurrentPositiveDropDownLabel_E  matlab.ui.control.Label
        VDDropDown_E                    matlab.ui.control.DropDown
        VDDDropDownLabel_E              matlab.ui.control.Label
        V0DropDown_E                    matlab.ui.control.DropDown
        V0DropDownLabel_E               matlab.ui.control.Label
        FileInfoEntryLabel_MicroSystem  matlab.ui.control.Label
        UIAxes_MicroSystem              matlab.ui.control.UIAxes
        CellularTissuePanel             matlab.ui.container.Panel
        DistanceToReferentialLabel      matlab.ui.control.Label
        DistanceToReferentialEditField_Z  matlab.ui.control.NumericEditField
        DistanceToReferentialEditField_Y  matlab.ui.control.NumericEditField
        DistanceToReferentialEditField_X  matlab.ui.control.NumericEditField
        SaveCopyCheckBox                matlab.ui.control.CheckBox
        AddcellstomodelButton           matlab.ui.control.Button
        FileInfoLabel_Biocells          matlab.ui.control.Label
        FileInfoEntryLabel_Biocells     matlab.ui.control.Label
        UIAxes_Biocells                 matlab.ui.control.UIAxes
        ToolsPanel_Parameters           matlab.ui.container.Panel
        SaveStudyPanel                  matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        SaveStudyLabel                  matlab.ui.control.Label
        LoadStudyPanel                  matlab.ui.container.Panel
        LoadMatlabFileButton_Parameters  matlab.ui.control.Button
        LoadStudyLabel                  matlab.ui.control.Label
        LoadGeo                         matlab.ui.container.Panel
        ClearButton_Biocells            matlab.ui.control.Button
        ImportButton_Biocells           matlab.ui.control.Button
        ClearButton_MicroSystem         matlab.ui.control.Button
        ImportButton_MicroSystem        matlab.ui.control.Button
        LoadGeometriesLabel             matlab.ui.control.Label
        SystemPanel                     matlab.ui.container.Panel
        MeasurementConfigurationLabel   matlab.ui.control.Label
        EntryProbeLabel                 matlab.ui.control.Label
        ElectrodesLabel                 matlab.ui.control.Label
        EntryDimensionLabel             matlab.ui.control.Label
        DimensionLabel                  matlab.ui.control.Label
        GEOMESTab                       matlab.ui.container.Tab
        ToolsPanel_Plot_GEOMES          matlab.ui.container.Panel
        MeshnumberEditField             matlab.ui.control.NumericEditField
        MeshnumberEditFieldLabel        matlab.ui.control.Label
        SaveAllFiguresButton_GEOMES     matlab.ui.control.Button
        MeshdensitySlider               matlab.ui.control.Slider
        MeshdensitySliderLabel          matlab.ui.control.Label
        UIAxesMesh_GEOMESResult         matlab.ui.control.UIAxes
        UIAxesGeometry_GEOMESResult     matlab.ui.control.UIAxes
        MaterialsTab                    matlab.ui.container.Tab
        BiologicalCellsPanel            matlab.ui.container.Panel
        BC2_DL_unit                     matlab.ui.control.DropDown
        BC2_DL_permit                   matlab.ui.control.NumericEditField
        BC2_DL_permit_Label             matlab.ui.control.Label
        BC2_DL_conduct_unit             matlab.ui.control.DropDown
        BC2_DL_conduct                  matlab.ui.control.NumericEditField
        BC2_DL_conduct_Label            matlab.ui.control.Label
        BC2_DLLabel                     matlab.ui.control.Label
        BC1_DL_unit                     matlab.ui.control.DropDown
        BC1_DL_permit                   matlab.ui.control.NumericEditField
        BC1_DL_permit_Label             matlab.ui.control.Label
        BC1_DL_conduct_unit             matlab.ui.control.DropDown
        BC1_DL_conduct                  matlab.ui.control.NumericEditField
        BC1_DL_conduct_Label            matlab.ui.control.Label
        BC1_DLLabel                     matlab.ui.control.Label
        BC2_Mem_unit                    matlab.ui.control.DropDown
        BC2_Mem_permit                  matlab.ui.control.NumericEditField
        BC2_Mem_permit_Label            matlab.ui.control.Label
        BC2_Mem_conduct_unit            matlab.ui.control.DropDown
        BC2_Mem_conduct                 matlab.ui.control.NumericEditField
        BC2_Mem_conduct_Label           matlab.ui.control.Label
        BC2_MemLabel                    matlab.ui.control.Label
        BC1_Mem_unit                    matlab.ui.control.DropDown
        BC1_Mem_permit                  matlab.ui.control.NumericEditField
        BC1_Mem_permit_Label            matlab.ui.control.Label
        BC1_Mem_conduct_unit            matlab.ui.control.DropDown
        BC1_Mem_conduct                 matlab.ui.control.NumericEditField
        BC1_Mem_conduct_Label           matlab.ui.control.Label
        BC1_MemLabel                    matlab.ui.control.Label
        BC2_Cyto_unit                   matlab.ui.control.DropDown
        BC2_Cyto_permit                 matlab.ui.control.NumericEditField
        BC2_Cyto_permit_Label           matlab.ui.control.Label
        BC2_Cyto_conduct_unit           matlab.ui.control.DropDown
        BC2_Cyto_conduct                matlab.ui.control.NumericEditField
        BC2_Cyto_conduct_Label          matlab.ui.control.Label
        BC2_CytoLabel                   matlab.ui.control.Label
        BC1_Cyto_unit                   matlab.ui.control.DropDown
        BC1_Cyto_permit                 matlab.ui.control.NumericEditField
        BC1_Cyto_permit_Label           matlab.ui.control.Label
        BC1_Cyto_conduct_unit           matlab.ui.control.DropDown
        BC1_Cyto_conduct                matlab.ui.control.NumericEditField
        BC1_Cyto_conduct_Label          matlab.ui.control.Label
        BC1_CytoLabel                   matlab.ui.control.Label
        CultureMediumPanel              matlab.ui.container.Panel
        CM2_unit                        matlab.ui.control.DropDown
        CM2_permit                      matlab.ui.control.NumericEditField
        CM2_permit_Label                matlab.ui.control.Label
        CM2_conduct_unit                matlab.ui.control.DropDown
        CM2_conduct                     matlab.ui.control.NumericEditField
        CM2_conduct_Label               matlab.ui.control.Label
        CM2Label                        matlab.ui.control.Label
        CM1_unit                        matlab.ui.control.DropDown
        CM1_permit                      matlab.ui.control.NumericEditField
        CM1_permit_Label                matlab.ui.control.Label
        CM1_conduct_unit                matlab.ui.control.DropDown
        CM1_conduct                     matlab.ui.control.NumericEditField
        CM1_conduct_Label               matlab.ui.control.Label
        CM1Label                        matlab.ui.control.Label
        ElectrodesPanel                 matlab.ui.container.Panel
        DoubleLayer_unit                matlab.ui.control.DropDown
        DoubleLayer_permit              matlab.ui.control.NumericEditField
        DoubleLayer_permit_Label        matlab.ui.control.Label
        DoubleLayer_conduct_unit        matlab.ui.control.DropDown
        DoubleLayer_conduct             matlab.ui.control.NumericEditField
        DoubleLayer_conduct_Label       matlab.ui.control.Label
        DoubleLayerLabel                matlab.ui.control.Label
        Gold_unit                       matlab.ui.control.DropDown
        Gold_permit                     matlab.ui.control.NumericEditField
        Gold_permit_Label               matlab.ui.control.Label
        Gold_conduct_unit               matlab.ui.control.DropDown
        Gold_conduct                    matlab.ui.control.NumericEditField
        Gold_conduct_Label              matlab.ui.control.Label
        GoldLabel                       matlab.ui.control.Label
        MicrosystemPanel                matlab.ui.container.Panel
        Glass_unit                      matlab.ui.control.DropDown
        Glass_permit                    matlab.ui.control.NumericEditField
        Glass_permit_Label              matlab.ui.control.Label
        Glass_conduct_unit              matlab.ui.control.DropDown
        Glass_conduct                   matlab.ui.control.NumericEditField
        Glass_conduct_Label             matlab.ui.control.Label
        GlassLabel                      matlab.ui.control.Label
        Plastic_Cap_permit              matlab.ui.control.NumericEditField
        Plastic_Cap_permit_Label        matlab.ui.control.Label
        Plastic_Cap_conduct             matlab.ui.control.NumericEditField
        Plastic_Cap_conduct_Label       matlab.ui.control.Label
        Plastic_Cap_permit_unit         matlab.ui.control.DropDown
        Plastic_Cap_conduct_unit        matlab.ui.control.DropDown
        Plastic_CapLabel                matlab.ui.control.Label
        PDMS_unit                       matlab.ui.control.DropDown
        PDMS_permit                     matlab.ui.control.NumericEditField
        PDMS_permit_Label               matlab.ui.control.Label
        PDMS_conduct_unit               matlab.ui.control.DropDown
        PDMS_conduct                    matlab.ui.control.NumericEditField
        PDMS_conduct_Label              matlab.ui.control.Label
        PDMSLabel                       matlab.ui.control.Label
        ToolsPanel_Plot_MATERIAL        matlab.ui.container.Panel
        FILELabel_Plot_MATERIAL         matlab.ui.control.Label
        STUDYTab                        matlab.ui.container.Tab
        BoundaryConditionsPanel         matlab.ui.container.Panel
        VoltageUnitDropDown             matlab.ui.control.DropDown
        VoltageDropEditField            matlab.ui.control.NumericEditField
        VoltageDropEditFieldLabel       matlab.ui.control.Label
        FrequencyDomainPanel            matlab.ui.container.Panel
        maxFrequencyEditField           matlab.ui.control.NumericEditField
        maxFrequencyEditFieldLabel      matlab.ui.control.Label
        maxFrequencyUnitDropDown        matlab.ui.control.DropDown
        PointsperdecadeEditField        matlab.ui.control.NumericEditField
        PointsperdecadeEditFieldLabel   matlab.ui.control.Label
        intFrequencyUnitDropDown        matlab.ui.control.DropDown
        intFrequencyEditField           matlab.ui.control.NumericEditField
        intFrequencyEditFieldLabel      matlab.ui.control.Label
        minFrequencyUnitDropDown        matlab.ui.control.DropDown
        minFrequencyEditField           matlab.ui.control.NumericEditField
        minFrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyLabel                  matlab.ui.control.Label
        ToolsPanel_Plot_STUDY           matlab.ui.container.Panel
        TerminalnumberEditField         matlab.ui.control.NumericEditField
        TerminalnumberEditFieldLabel    matlab.ui.control.Label
        StudynumberEditField            matlab.ui.control.NumericEditField
        StudynumberEditFieldLabel       matlab.ui.control.Label
        RunStudy                        matlab.ui.container.Panel
        FigCheckBox                     matlab.ui.control.CheckBox
        LogCheckBox                     matlab.ui.control.CheckBox
        PreviewallButton                matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        RunStudyLabel                   matlab.ui.control.Label
        StudiesListBox                  matlab.ui.control.ListBox
        TransportLatticeListBox         matlab.ui.control.ListBox
        SPICETab                        matlab.ui.container.Tab
        TabGroup_SPICE                  matlab.ui.container.TabGroup
        Bode_SPICETab                   matlab.ui.container.Tab
        UIAxesPhase_Bode_SPICE          matlab.ui.control.UIAxes
        UIAxesMagnitude_Bode_SPICE      matlab.ui.control.UIAxes
        Nyquist_SPICETab                matlab.ui.container.Tab
        UIAxes_Nyquist_SPICE            matlab.ui.control.UIAxes
        VfminMapSPICETab                matlab.ui.container.Tab
        ZSliceSlider                    matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        UIAxes_PotentialMapfmin_SPICE   matlab.ui.control.UIAxes
        VfintMapSPICETab                matlab.ui.container.Tab
        UIAxes_PotentialMapfint_SPICE   matlab.ui.control.UIAxes
        VfmaxMapSPICETab                matlab.ui.container.Tab
        UIAxes_PotentialMapfmax_SPICE   matlab.ui.control.UIAxes
        ToolsPanel_Plot_SPICE           matlab.ui.container.Panel
        FILELabel_Plot_SPICE            matlab.ui.control.Label
        SaveAllFiguresButton_SPICE      matlab.ui.control.Button
        FEMTab                          matlab.ui.container.Tab
        TabGroup_FEM                    matlab.ui.container.TabGroup
        Bode_FEMTab                     matlab.ui.container.Tab
        UIAxesPhase_Bode_FEM            matlab.ui.control.UIAxes
        UIAxesMagnitude_Bode_FEM        matlab.ui.control.UIAxes
        Nyquist_FEMTab                  matlab.ui.container.Tab
        UIAxes_Nyquist_FEM              matlab.ui.control.UIAxes
        VfminMapFEMTab                  matlab.ui.container.Tab
        UIAxes_PotentialMapfmin_FEM     matlab.ui.control.UIAxes
        VfintMapFEMTab                  matlab.ui.container.Tab
        UIAxes_PotentialMapfint_FEM     matlab.ui.control.UIAxes
        VfmaxMapFEMTab_FEM              matlab.ui.container.Tab
        UIAxes_PotentialMapfmax_FEM     matlab.ui.control.UIAxes
        ToolsPanel_Plot_FEM             matlab.ui.container.Panel
        FILELabel_Plot_FEM              matlab.ui.control.Label
        SaveAllFiguresButton_FEM        matlab.ui.control.Button
        ANATab                          matlab.ui.container.Tab
        TabGroup_ANA                    matlab.ui.container.TabGroup
        Bode_ANATab                     matlab.ui.container.Tab
        UIAxesPhase_Bode_ANA            matlab.ui.control.UIAxes
        UIAxesMagnitude_Bode_ANA        matlab.ui.control.UIAxes
        Nyquist_ANATab                  matlab.ui.container.Tab
        UIAxes_Nyquist_ANA              matlab.ui.control.UIAxes
        VfminMapANATab                  matlab.ui.container.Tab
        UIAxes_PotentialMapfmin_ANA     matlab.ui.control.UIAxes
        VfintMapANATab                  matlab.ui.container.Tab
        UIAxes_PotentialMapfint_ANA     matlab.ui.control.UIAxes
        VfmaxMapFEMTab_ANA              matlab.ui.container.Tab
        UIAxes_PotentialMapfmax_ANA     matlab.ui.control.UIAxes
        ToolsPanel_Plot_ANA             matlab.ui.container.Panel
        FILELabel_Plot_ANA              matlab.ui.control.Label
        SaveAllFiguresButton_ANA        matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        OpeninexternalfigureMenu        matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % [Created]
    properties (Access = public)
        settings = struct("dimensions", 0, "probes", 0, "workingDir", "", "srcDir", ""); % setting parameters
        externalFigure = figure("Name","External Figure", "Visible","off", "NumberTitle","off"); % the only figure in an other window attached to the app
        cells; % imported cells from IMARIS
        loadedModel = struct("model", [], "filename", "", "path", ""); % imported model from COMSOL
        lastAxisClicked = 0; % record the last ui axis that has been clicked on (context menu purpose)
        lastResultFolderName;
    end
    
    properties (Access = public)
        tlm % Description
        model
    end

    
    % [Created]
    methods (Access = public)
        
        % ----- Initialization -----
        
        % Initialize the cells property as a struct.
        % Input:
        %   app - Self app
        function initCells(app)
            app.cells = struct("number", 0, ...           % number of cells in the system
                           "angles_axisA", {[]}, ...      % rotations of the direction A in the (x,y,z) coordinates system
                           "size", struct("X",0, "Y", 0, "Z", 0), ...       % radius in each local direction of the approximated cells (ellipsoidal)
                           "direction", struct("A",{[]}, "B", {[]}, "C", {[]}), ...     % local axis of the approximated cells (ellipsoidal)
                           "pos", struct("X", 0, "Y", 0, "Z", 0), ...       % position
                           "distance_to_scan_referential", 0, ...           % distance to a fixed referential point in the COMSOL model
                           "filename", "");
            % See if there are cells in the loaded model
            if app.loadedModel.filename ~= "" % no COMSOL model loaded yet
                app.cells.number = countElements(app, "Cell");
            end
        end
        
        
        % ----- Parameters -----
        
        % Get all ui children of a given parent that has the given ui type.
        % Inputs:
        %   app - Self app
        %   parent - Parent ui component
        %   type - UI type of the searched components
        function [components] = getChildren(app, parent, type)
            components = [];
            children = parent.Children;
            for i = 1:length(children)
                p = properties(children(i)); % list the properties of the component
                hasChildren = length(p(ismember(p, 'Children') == 1)) > 0;
                
                if children(i).Type == type
                    components = [components children(i)];
                elseif hasChildren
                    components = [components getChildren(app, children(i), type)]; % recurisivity
                end
            end
        end
        
        % Change the available parameters given the system parameters at creation/load of parameters.
        % Input :
        %   app - Self app
        function refreshParametersDisplay(app)
            % Change system labels
            app.EntryDimensionLabel.Text = strcat(string(app.settings.dimensions),'D');
            app.EntryProbeLabel.Text = string(app.settings.probes);
            
            % Update electrode roles limits
            app.V0DropDown_E.Items = string(1:app.settings.probes);
            app.VDDropDown_E.Items = string(1:app.settings.probes);
            app.CurrentPositiveDropDown_E.Items = string(1:app.settings.probes);
            app.CurrentNegativeDropDown_E.Items = string(1:app.settings.probes);
            
            % Write above the current file
            app.FoldernameLabel.Text = app.settings.workingDir;
        end
        
        % Update the file information depending on the panel.
        % Inputs:
        %   app - Self app
        %   panelName - Name of the panel
        %   filename - Name of the imported file to display
        function dispFileInfo(app, panelName)
            switch panelName
                case "biocells"
                    fileInfo = sprintf('%s\n%s',strcat("Number of cells : ", string(app.cells.number)), strcat("File name : ", app.cells.filename));
                    % fileInfo = strcat("Number of cells : ", string(app.cells.number), "\n", "File name : ", filename);
                    app.FileInfoEntryLabel_Biocells.Text = fileInfo;
                case "system"
                    fileInfo = strcat("File name : ", app.loadedModel.filename);
                    app.FileInfoEntryLabel_MicroSystem.Text = fileInfo;
            end
        end
        
        % Append all editable children values from given parent to given array.
        % Inputs: 
        %   app - Self app 
        %   parent - Parent component
        %   array - Destination array
        % Output:
        %   array - Filled array
        function [array] = appendChildrenToArray(app, parent, array)
            children = parent.Children;
            for i = 1:length(children)
                % Add only items which have an editable value
                if children(i).Tag == "dropdown" ||  children(i).Type == "uinumericeditfield"
                    array = [array, string(children(i).Value)];
                end
            end
        end
        
        % Set all editable fields of given parent to values from given array.
        % This function relies on the order in which values were saved :
        % saved files will not be compatible after an interface update.
        % Inputs: 
        %   app - Self app 
        %   parent - Destination parent component
        %   array - Source array
        function setValuesFromArray(app, parent, array)
            children = parent.Children;
            idx = 1;
            for i = 1:length(children)
                % Add only items which have an editable value
                if children(i).Tag == "dropdown"
                    children(i).Value = array(idx);
                    idx = idx + 1;
                elseif  children(i).Type == "uinumericeditfield"
                    children(i).Value = str2num(array(idx));
                    idx = idx + 1;
                end
            end
        end
        
        % Save all parameters to user-specified file.
        % Input:
        %   app - Self app
        function saveParameters(app)
            savedParameters = [];
            
            % Save global app settings
            savedParameters.settings = app.settings;
            savedParameters.log = app.LogCheckBox.Value;

            % modif 29/10 s
            savedParameters.fig = app.FigCheckBox.Value;
            savedParameters.studies = app.StudiesListBox.Value;
            savedParameters.V0 = app.V0DropDown_E.Value;
            savedParameters.VDD = app.VDDropDown_E.Value;
            savedParameters.curpos = app.CurrentPositiveDropDown_E.Value;
            savedParameters.curneg = app.CurrentNegativeDropDown_E.Value;
            % modif 29/10 f
            
            % Save Mesh Density
            savedParameters.meshDensity = app.MeshdensityEditField.Value;
            
            % Save Biocell geometry
            savedParameters.cells = app.cells;
            
            % Save Model geometry
            savedParameters.model.path = app.loadedModel.path;
            savedParameters.model.filename = app.loadedModel.filename;
            % COMSOL Model Object cannot be saved in .mat file ( root is not serializable )
            
            % Save Physics parameters
            savedParameters.physics = [];
            savedParameters.physics = appendChildrenToArray(app, app.FrequencyDomainPanel, savedParameters.physics);
            
            % Let user chose name and location of saved file
            [filename, pathname] = uiputfile('*.mat','Save Parameters As');
            if filename == 0
                return
            end
            newfilename = fullfile(pathname, filename);
            save(newfilename, "savedParameters");

            uialert(app.UIFigure, "Settings successfully saved!", "Action completed", "Icon", "success");
        end
       
        % Fill interface with parameters from .mat file.
        % Input: 
        %   app - Self app
        % Output:
        %   success - Successfully loaded file logical
        function [success] = loadParameters(app)
            success = 0;
            [savedParametersFile, filePath] = uigetfile(".mat", "Chose Parameters File");
            figure(app.UIFigure);
            if savedParametersFile == 0 % action canceled
                return
            end
            
            loadingBar = uiprogressdlg(app.UIFigure, 'Title', 'Loading parameters from .mat','Indeterminate','on');
            drawnow
            try
            addpath(filePath);
            data = load(savedParametersFile);
            savedParameters = data.savedParameters;
            
            % Load global app settings and update display
            app.settings = savedParameters.settings;
            app.LogCheckBox.Value = savedParameters.log;
            refreshParametersDisplay(app)

            % modif 29/10 s
            app.FigCheckBox.Value = savedParameters.fig;
            app.StudiesListBox.Value = savedParameters.studies;
            savedParameters.V0 = app.V0DropDown_E.Value;
            app.VDDropDown_E.Value = savedParameters.VDD;
            app.CurrentPositiveDropDown_E.Value = savedParameters.curpos;
            app.CurrentNegativeDropDown_E.Value = savedParameters.curneg;
            % modif 29/10 f
           
            % Load Mesh Density
            app.MeshdensityEditField.Value = savedParameters.meshDensity;
            
            % Load Biocell geometry
            app.cells = savedParameters.cells;

            % Load Model geometry
            app.loadedModel.path = savedParameters.model.path;
            app.loadedModel.filename = savedParameters.model.filename;
            app.loadedModel.model = mphload(savedParameters.model.path); % saving COMSOL objects is not supported, reloading from file is necessary
            checkModelCells(app); % update add imaris cells button enablement
            
            % Load Physics parameters 
            setValuesFromArray(app, app.FrequencyDomainPanel, savedParameters.physics);
            
            % Update Info
            dispFileInfo(app, "biocells");
            dispFileInfo(app, "system");
            
            % Preview system
            cla(app.UIAxes_MicroSystem);
            try 
                mphgeom(app.loadedModel.model, 'geom1', 'facemode', 'off', 'parent', app.UIAxes_MicroSystem);
            catch
                 warning('COMSOL function "mphgeom" does not quite handle UI axes.');
            end
            grid(app.UIAxes_MicroSystem, 'on');
            
            % Preview Cells
            cla(app.UIAxes_Biocells);
            IMSCellPreview(app, app.UIAxes_Biocells);
            catch
                uialert(app.UIFigure, "Invalid save data", "Error", "Icon", "error");
                success = 0;
                return
            end
            close(loadingBar);
            
            uialert(app.UIFigure, "Settings successfully loaded!", "Action completed", "Icon", "success");
            success = 1;
        end
        
        % Count the number of elements from the loaded COMSOL model that have a label containing the given string. 
        % Inputs:
        %   app - Self app
        %   type - String (Cell | Electrode | Bioreactor | Pt) (case sensitive)
        % Output:
        %   count - Number of COMSOL model elements
        function [count] = countElements(app, type)
            count = 0;
            childrenTags = string(app.loadedModel.model.geom('geom1').feature().tags);
            for i = 1:length(childrenTags)
                label = string(app.loadedModel.model.geom('geom1').feature(childrenTags(i)).label);
                if contains(label, type)
                    count = count + 1;
                end
            end
        end
        
        
        % ----- Plot -----
        
        % Initialize the external Figure from app properties.
        % Input:
        %   app - Self app
        function initFigure(app)
            if ~isvalid(app.externalFigure) % has been deleted
                app.externalFigure = figure("Name","External Figure", "NumberTitle","off");
            else
                app.externalFigure.Visible = 'on'; % at app start, is invisible
            end
        end
        
        % Plot the geometry of each loaded cell from IMS folder as an ellipsoid.
        % Inputs :
        %   app - Self app
        %   ax - Axes where the geometry will be plotted
        % Output :
        %   h - Surface of the last ellipsoid
        function [h] = IMSCellPreview(app, ax)
            if app.cells.filename == "" % no folder imported yet
                return
            end
            
            hold(ax, 'on');
            for i = 1: app.cells.number
                [x_pos, y_pos, z_pos] = deal(app.cells.pos.X(i) , app.cells.pos.Y(i), app.cells.pos.Z(i));
                [eX, eY, eZ] = ellipsoid(x_pos, y_pos, z_pos, app.cells.size.X(i), app.cells.size.Y(i), app.cells.size.Z(i));
                h = surf(ax, eX, eY, eZ);
                
                angles = cell2mat(app.cells.angles_axisA(i));
                rotate(h, [1 0 0], angles(1), [x_pos, y_pos, z_pos]); % rotations of axis A in (x,y,z) coordinate system
                rotate(h, [0 1 0], angles(2), [x_pos, y_pos, z_pos]); %
                rotate(h, [0 0 1], angles(3), [x_pos, y_pos, z_pos]); %
                
                h.FaceColor = [0.68,0.82,0.50];
                h.FaceAlpha = 0.6;
                h.EdgeAlpha = 0.1;
            end
            hold(ax, 'off');
            axis(ax, 'equal');
            view(ax, 3); % start with a 3D perpective view
            grid(ax, 'on');
        end
        
        % ----- Errors -----
        
        % Check if all mandatory and needed csv files are in the selected IMS folder.
        % Inputs:
        %   app - Self app
        %   dirpath - IMS folder path
        % Output:
        %   success - Bool true if all files are there
        function [success] = checkIMSFiles(app, dirpath)
            success = 0;
            
            % Mandatory files to find
            filesToCheck = ["_Position.csv" "_Ellipsoid_Axis_A.csv" "_Ellipsoid_Axis_B.csv" "_Ellipsoid_Axis_C.csv" ...
                "_Ellipsoid_Axis_Length_A.csv" "_Ellipsoid_Axis_Length_B.csv" "_Ellipsoid_Axis_Length_C.csv"];
            
            [~, dirname] = fileparts(dirpath); % extract the directory name
            dirinfo = dir(dirpath);
            listing = string({dirinfo.name}); % only get the name of the files
            
            % Add folder name to needed files
            dirname = dirname.erase("_Statistics"); % retrieve IMS file name
            for i = 1:length(filesToCheck)
                filesToCheck(i) = strcat(dirname, filesToCheck(i));
            end
            
            % Check for missing files
            res = ismember(filesToCheck, listing); % logical array
            if res
                success = 1;
            else
                missingFiles = filesToCheck(res == 0);
                % Alert : the first on the list of the missing files if more than 1
                uialert(app.UIFigure, strcat(missingFiles(1)," file is missing."), "Missing files", "Modal", true);
            end
        end
        
        % Check if all mandatory variables exits or are not null for run.
        % Input:
        %   app - Self app
        % Output:
        %   success - Bool true if all variables are correct
        function [success] = checkMandatoryFields(app)
            success = 0;
            
             if app.loadedModel.filename == "" % no COMSOL model imported yet
                  uialert(app.UIFigure, "Cannot run without COMSOL model.", "Execution error", "Modal", true);
                  return
             end
             
             % Check Physics edit fields
             children = app.FrequencyDomainPanel.Children;
             for i = 1:length(children)
                 if children(i).Type == "uinumericeditfield" && children(i).Value == 0
                      uialert(app.UIFigure, "Physics parameters cannot be null.", "Execution error", "Modal", true);
                      return
                 end
             end
             
             success = 1; 
        end
        
        % Check that the min frequency is lower than the max frequency.
        % Input:
        %   app - Self app
        % Output:
        %   success - Bool true if the range is correct
        function [success] =  checkFrequencyRange(app)
            success = 0;
            min = app.minFrequencyEditField.Value; % initialize
            max = app.intFrequencyEditField.Value;
            
            % Adjust value according to selected unit
            % Minimum frequency 
            switch app.minFrequencyUnitDropDown.Value
                case "kHz"
                    min = min * 1000;
                case "MHz"
                    min = min * 1000000;
            end
            % Maximum frequency
            switch app.intFrequencyUnitDropDown.Value
                case "kHz"
                    max = max * 1000;
                case "MHz"
                    max = max * 1000000;
            end
            
            if min < max % check for correct order
                success = 1;
                return
            else
                uialert(app.UIFigure, "The frequency range is incorrect : min must be higher than max frequency.", "Execution error", "Modal", true);
            end
        end
        
        % Check if the loaded model on import is already a copy containing IMARIS cells
        % or if cells has been created (in COMSOL)
        % and prevent superposing imaris cells from different or same scans.
        % Input:
        %   app - Self app
        function checkModelCells(app)
            if contains(app.loadedModel.filename, "imaris_cells.mph") || countElements(app, "Cell") > 0
                app.AddcellstomodelButton.Enable = 'off';
                app.SaveCopyCheckBox.Enable = 'off';
            else
                app.AddcellstomodelButton.Enable = 'on';
                app.SaveCopyCheckBox.Enable = 'on';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add folders to working directory
            addpath(genpath('./assets/'));
            addpath(genpath('./utils/'));
            addpath(genpath('./src/'));
            
            % Assign current folder to working directory by default and set source directory
            app.settings.srcDir = fileparts(mfilename('fullpath'));
            
            % --- NEW: Defining the default simulation folder ---
            app.settings.workingDir = fullfile(app.settings.srcDir, 'simulation');

            % --- NEW: Automatically create the folder if it does not exist ---
            if ~exist(app.settings.workingDir, 'dir')
                mkdir(app.settings.workingDir);
            end

            app.FilePathLabel.Text = app.settings.workingDir;
            
            % Initialize images (has to be done after addpath(assets))
            app.SaveButton.Icon = "saveicon.png";
            app.BiocadLogo.ImageSource = "biocad.png";
            
            % Initialize properties
            initCells(app);       
            
            movegui(app.UIFigure,"center") 	% move to center
            delete(app.LoadingPanel) % let user access app
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete all window of the app
            delete(app.externalFigure)
            delete(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Check if parameters are correct (ui alert if not)
            success = checkMandatoryFields(app);
            if ~success
                return
            end
            
            success = checkFrequencyRange(app);
            if ~success
                return
            end
            
            % No problems with the parameters :
            loadingBar = uiprogressdlg(app.UIFigure, 'Title', 'Computing','Indeterminate','on');
            drawnow
            
            assignAndRun(app); % computation runner

            if isfield(app.tlm.var, 'EpaisseurChambre')
                % On récupère la valeur (ex: 100 microns)
                H = app.tlm.var.EpaisseurChambre; 
                
                % On configure le Slider dynamiquement
                app.ZSliceSlider.Limits = [0, H];
                
                % On positionne le curseur par défaut à la moitié de la chambre
                app.ZSliceSlider.Value = H/2;
            else
                % Sécurité si le calcul a échoué
                app.ZSliceSlider.Enable = 'off';
            end

            % Draw geometry of microsystem in GEOMES Tab
            cla(app.UIAxesGeometry_GEOMESResult);
            try 
                % Plot the geometry in the preview axes
                mphgeom(app.loadedModel.model, 'geom1', 'facemode', 'off', 'parent', app.UIAxesGeometry_GEOMESResult);
            catch
                 warning('COMSOL function "mphgeom" for GEOMES does not quite handle UI axes.');
            end

            % Draw mesh of microsystem in GEOMES Tab

            cla(app.UIAxesMesh_GEOMESResult);
            try 
                % Plot the geometry in the preview axes
                mphmesh(app.loadedModel.model, app.tlm.conf.mesh_label, 'Facealpha', 0.4, 'parent', app.UIAxesMesh_GEOMESResult);
            catch
                 warning('COMSOL function "mphmesh" for GEOMES does not quite handle UI axes.');
            end
            
            axis(app.UIAxesMesh_GEOMESResult, 'equal', 'tight');
            view(app.UIAxesMesh_GEOMESResult, 3);
            
            close(loadingBar)

            %app.SaveAllFiguresButton_GEOMES.Enable = 'on';
            %figure(app.UIFigure); % bring app window to foreground
            %app.MainTabGroup.SelectedTab = app.GEOMESTab; % focus on the GEOMES tab
%Suppress by V Senez on 28/01/2025
           % cla(app.UIAxesGeometry_ModelResult_SPICE);

          %  try 
                % Plot the geometry in the preview axes
           %     mphgeom(app.loadedModel.model, 'geom1', 'facemode', 'off', 'parent', app.UIAxesGeometry_ModelResult_SPICE);
          %  catch
         %        warning('COMSOL function "mphgeom" for SPICE does not quite handle UI axes.');
         %   end
            
        %    cla(app.UIAxesMesh_ModelResult_SPICE);
         %   try 
                % Plot the geometry in the preview axes
         %       mphmesh(app.loadedModel.model, 'mesh1', 'Facealpha', 0.4, 'parent', app.UIAxesMesh_ModelResult_SPICE);
         %   catch
         %        warning('COMSOL function "mphmesh" for SPICE does not quite handle UI axes.');
         %   end
            
         %   axis(app.UIAxesMesh_ModelResult_SPICE, 'equal', 'tight');
         %   view(app.UIAxesMesh_ModelResult_SPICE, 3);
            
        %    close(loadingBar)
        %    app.SaveAllFiguresButton_SPICE.Enable = 'on';
        %    figure(app.UIFigure); % bring app window to foreground
        %    app.MainTabGroup.SelectedTab = app.SPICETab; % focus on the first results tab (Spice Plot)

        %    cla(app.UIAxesGeometry_ModelResult_FEM);
        %    try 
                % Plot the geometry in the preview axes
       %         mphgeom(app.loadedModel.model, 'geom1', 'facemode', 'off', 'parent', app.UIAxesGeometry_ModelResult_FEM);
        %    catch
        %         warning('COMSOL function "mphgeom" for FEM does not quite handle UI axes.');
        %    end
            
        %    cla(app.UIAxesMesh_ModelResult_FEM);
        %    try 
                % Plot the geometry in the preview axes
        %        mphmesh(app.loadedModel.model, 'mesh1', 'Facealpha', 0.4, 'parent', app.UIAxesMesh_ModelResult_FEM);
        %    catch
        %         warning('COMSOL function "mphmesh" for FEM does not quite handle UI axes.');
        %    end
            
        %    axis(app.UIAxesMesh_ModelResult_FEM, 'equal', 'tight');
        %    view(app.UIAxesMesh_ModelResult_FEM, 3);
            
         %   close(loadingBar)
        %    app.SaveAllFiguresButton_FEM.Enable = 'on';
        %    figure(app.UIFigure); % bring app window to foreground
        %    app.MainTabGroup.SelectedTab = app.FEMTab; % focus on the first results tab (FEM Plot)
   %end of suppression on 28/01/2025
        end

        % Callback function
        function MeshdensitySliderValueChanged(app, event)
            value = app.MeshdensityEditField.Value;
            
            % Determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % Move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
        end

        % Button pushed function: PreviewallButton
        function PreviewallButtonPushed(app, event)
            initFigure(app);
            if class(app.externalFigure.Children) == "matlab.graphics.GraphicsPlaceholder"
                ax = axes(app.externalFigure); % create new axes
            else
                ax = app.externalFigure.Children(end); % last element is axes component (else legend)
                cla(ax);
            end
            
            hold(ax, "on")
                if app.cells.number ~= 0
                    IMSCellPreview(app, ax); % cells to preview
                end
                
                if ~strcmp(class(app.loadedModel.model), "double") % model to preview
                    mphgeom(app.loadedModel.model, 'geom1', 'facemode', 'off', 'parent', ax);
                end
            hold(ax, "off")
            
            if app.settings.dimensions == 3
                view(ax, [-37.5, 30]) % specify 3D view for the axes (view offset changed to see the 3D space)
            end
            axis(ax, "equal");
            figure(app.externalFigure) % external figure window to foreground
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveParameters(app);
            figure(app.UIFigure);
        end

        % Size changed function: LoadStudyPanel
        function LoadMatlabFileButton_ParametersPushed(app, event)
            loadParameters(app);
            figure(app.UIFigure);
        end

        % Button pushed function: ImportButton_Biocells
        function LoadIMSFolderButtonPushed(app, event)
            path = uigetdir(app.settings.workingDir);
            
            if path == 0 % action canceled
                figure(app.UIFigure) % focus on main interface
                return
            end

            figure(app.UIFigure);
            loadingBar = uiprogressdlg(app.UIFigure, 'Title', 'Loading Cells','Indeterminate','on');
            drawnow
            path = convertCharsToStrings(path);
            [~, dirname] = fileparts(path);
            if ~contains(dirname, "_Statistics") % does not contains the needed files
                uialert(app.UIFigure, "Please chose a *_Statistics folder", "Invalid Folder Name", "Modal", true);
                return
            elseif ~checkIMSFiles(app, path) % missing csv files
                return
            end
            dirname = dirname.erase("_Statistics");
            addpath(path);
            
            % Read and store in cells property the information
            posData = readtable(strcat(dirname,"_Position.csv"), "VariableNamingRule","preserve");
            
            basisData_A = readtable(strcat(dirname,"_Ellipsoid_Axis_A.csv"), "VariableNamingRule","preserve"); 
            basisData_B = readtable(strcat(dirname,"_Ellipsoid_Axis_B.csv"), "VariableNamingRule","preserve"); 
            basisData_C = readtable(strcat(dirname,"_Ellipsoid_Axis_C.csv"), "VariableNamingRule","preserve"); 
            
            sizeData_A = readtable(strcat(dirname,"_Ellipsoid_Axis_Length_A.csv"), "VariableNamingRule","preserve");
            sizeData_B = readtable(strcat(dirname,"_Ellipsoid_Axis_Length_B.csv"), "VariableNamingRule","preserve");
            sizeData_C = readtable(strcat(dirname,"_Ellipsoid_Axis_Length_C.csv"), "VariableNamingRule","preserve");
            
            app.cells.number = height(posData);
            
            for i = 1:height(posData)
                id = posData(i,:).("ID");
                
                x_pos = posData(i,:).("Position X");
                y_pos = posData(i,:).("Position Y");
                z_pos = posData(i,:).("Position Z");
                app.cells.pos.X(i) = x_pos;
                app.cells.pos.Y(i) = y_pos;
                app.cells.pos.Z(i) = z_pos;
                
                basisRow_A = basisData_A(basisData_A.ID == id, :);
                basisRow_B = basisData_B(basisData_B.ID == id, :);
                basisRow_C = basisData_C(basisData_C.ID == id, :);
                
                basisVector_A = [basisRow_A.("Ellipsoid Axis A X") basisRow_A.("Ellipsoid Axis A Y") basisRow_A.("Ellipsoid Axis A Z")];
                basisVector_B = [basisRow_B.("Ellipsoid Axis B X") basisRow_B.("Ellipsoid Axis B Y") basisRow_B.("Ellipsoid Axis B Z")];
                basisVector_C = [basisRow_C.("Ellipsoid Axis C X") basisRow_C.("Ellipsoid Axis C Y") basisRow_C.("Ellipsoid Axis C Z")];
                app.cells.direction.A{i} = basisVector_A;
                app.cells.direction.B{i} = basisVector_B;
                app.cells.direction.C{i} = basisVector_C;
                
                axisLengthRow_A = sizeData_A(sizeData_A.ID == id, :);
                axisLengthRow_B = sizeData_B(sizeData_B.ID == id, :);
                axisLengthRow_C = sizeData_C(sizeData_C.ID == id, :);
                
                axisLength_A = axisLengthRow_A.("Ellipsoid Axis Length A");
                axisLength_B = axisLengthRow_B.("Ellipsoid Axis Length B");
                axisLength_C = axisLengthRow_C.("Ellipsoid Axis Length C");
                app.cells.size.X(i) = axisLength_A;
                app.cells.size.Y(i) = axisLength_B;
                app.cells.size.Z(i) = axisLength_C;
                
                ax = rad2deg(atan(basisVector_A(3) / basisVector_A(2)));
                ay = rad2deg(atan(basisVector_A(3) / basisVector_A(1)));
                az = rad2deg(atan(basisVector_A(2) / basisVector_A(1)));
                app.cells.angles_axisA{i} = [ax, ay, az];
            end
            
            app.cells.filename = dirname;
            
            if event.Source == app.LoadIMSFolderButton_Menu
                app.MainTabGroup.SelectedTab = app.FileTab;
            end
            
            close(loadingBar);
            
            dispFileInfo(app, "biocells");
            cla(app.UIAxes_Biocells);
            IMSCellPreview(app, app.UIAxes_Biocells);
        end

        % Button pushed function: ImportButton_MicroSystem
        function LoadMPHButtonPushed(app, event)
            [filename, path] = uigetfile(".mph", "Chose COMSOL Geometry File for Global System");
            figure(app.UIFigure);
            if filename == 0 % action canceled
                return
            end

            loadingBar = uiprogressdlg(app.UIFigure, 'Title', 'Loading COMSOL geometry','Indeterminate','on');
            drawnow
            
            app.loadedModel.model = mphload(strcat(path, filename));
            app.loadedModel.filename = filename;
            app.loadedModel.path = strcat(path, filename);
            
            % Get settings parameters from mph model
            app.settings.dimensions = app.loadedModel.model.geom('geom1').getSDim;
            app.settings.probes = countElements(app, "Electrode");            
            refreshParametersDisplay(app);
            % Get number of cells if not loaded from an IMS folder (supposely in the mph file)
            if app.cells.number == 0
                app.cells.number = countElements(app, "Cell");
            end
            
            dispFileInfo(app, "system");
            cla(app.UIAxes_MicroSystem);
            try 
                % Plot the geometry in the preview axes
                mphgeom(app.loadedModel.model, 'geom1', 'facemode', 'off', 'parent', app.UIAxes_MicroSystem);
            catch
                 warning('COMSOL function "mphgeom" does not quite handle UI axes.');
            end
            
            
            % Update add imaris cells button enablement
            checkModelCells(app);
            
            close(loadingBar)
            grid(app.UIAxes_MicroSystem, 'on');
        end

        % Button pushed function: ClearButton_Biocells
        function ClearButton_BiocellsPushed(app, event)
            initCells(app);
            cla(app.UIAxes_Biocells);
            dispFileInfo(app, "biocells");
        end

        % Button pushed function: ClearButton_MicroSystem
        function ClearButton_MicroSystemPushed(app, event)
            % Clear fields
            app.loadedModel.model = [];
            app.loadedModel.path = "";
            app.loadedModel.filename = "";
            
            cla(app.UIAxes_MicroSystem);
            dispFileInfo(app, "system");
            
            app.AddcellstomodelButton.Enable = 'on';
            app.SaveCopyCheckBox.Enable = 'on';
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            % Simple credits for developpers of the biocad app
            message = sprintf('Application made for SENEZ Vincent by : \n\nARBACHE Rémi & DALMAS Eugénie\n\nMarch - April 2021');
            title = 'About this app';
            selection = uiconfirm(app.UIFigure,message,title,...
                           'Options',{'GitHub','Ok'},...
                           'DefaultOption',2,'CancelOption',2);
            if selection == "GitHub"
                 web('https://github.com/RemiArbache/BIOCAD'); 
            end
            figure(app.UIFigure);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.settings.workingDir = uigetdir(); % get the directory
            if app.settings.workingDir ~= 0
                app.FilePathLabel.Text = app.settings.workingDir;
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: AddcellstomodelButton
        function AddcellstomodelButtonPushed(app, event)
            % Check for missing files
            if app.loadedModel.filename == ""
                uialert(app.UIFigure, "No COMSOL model yet loaded.", "Missing files");
                return
            elseif app.cells.filename == ""
                uialert(app.UIFigure, "No IMARIS folder yet loaded.", "Missing files");
                return
            end
            
            loadingBar = uiprogressdlg(app.UIFigure, 'Title', 'Adding IMARIS cells to COMSOL model','Indeterminate','on');
            drawnow
            app.loadedModel.model = buildImarisCells(app, app.SaveCopyCheckBox.Value);
            
            % Refresh model preview and file info
            dispFileInfo(app, "system");
            cla(app.UIAxes_MicroSystem);
            try 
                % Plot the geometry in the preview axes
                mphgeom(app.loadedModel.model, 'geom1', 'facemode', 'off', 'parent', app.UIAxes_MicroSystem);
            catch
                warning('COMSOL function "mphgeom" does not quite handle UI axes.');
            end
            
            close(loadingBar);
            
            % After add of IMS cells to model, disable button
            app.AddcellstomodelButton.Enable = 'off';
            app.SaveCopyCheckBox.Enable = 'off';
        end

        % Button down function: UIAxesMagnitude_Bode_SPICE, 
        % ...and 6 other components
        function UIAxesButtonDown(app, event)
            app.lastAxisClicked = event.Source;
        end

        % Menu selected function: OpeninexternalfigureMenu
        function OpeninexternalfigureMenuSelected(app, event)
            initFigure(app);
            clf(app.externalFigure);
            
            % Copy axes to external figure
            ax = copyobj(app.lastAxisClicked, app.externalFigure);
            
            % Resize and center axes
            ax.Units = 'normalized';
            ax.OuterPosition = [.1 .1 .8 .8];
            
            figure(app.externalFigure);
        end

        % Button pushed function: SaveAllFiguresButton_GEOMES, 
        % ...and 1 other component
        function SaveAllFiguresButton_SPICEPushed(app, event)
            loadingBar = uiprogressdlg(app.UIFigure, 'Title', 'Saving all figures','Indeterminate','on');
            drawnow
            % Search in tab group children the axes
            uiAxes = getChildren(app, app.SPICETab, "axes");
            % Create parent figure in minimized state
            fig = figure("Name", "Saved Figure", "Visible", "on", "WindowStyle", "normal", "WindowState", "minimized");
            for ax = uiAxes

                % Plot axes
                ax = copyobj(ax, fig);
                ax.Units = 'normalized';
                ax.OuterPosition = [.1 .1 .8 .8];
                axis(ax, 'tight');

                % Save figure as .fig file
                [~,name,~] = fileparts(app.loadedModel.filename);            
                saveas(fig, strcat(app.settings.workingDir, "\", app.lastResultFolderName, "\", name, ax.Tag), 'fig');
                delete(ax)
            end
            
            % Delete figure
            delete(fig);
            
            % Finish task
            close(loadingBar);
            
            uialert(app.UIFigure, "Successfully saved all figures to store directory.", "Save", "Icon", "success", "Modal", true);
        end

        % Callback function
        function ImportButton_MicroSystemPushed(app, event)
            
        end

        % Callback function
        function SaveAllFiguresButton_GEOMESPushed(app, event)
            
        end

        % Value changed function: ZSliceSlider
        function ZSliceSliderValueChanged(app, event)
            % 1. Récupérer la valeur actuelle du slider (en microns)
            z_value = app.ZSliceSlider.Value;

            % Mise à jour instantanée sans toucher aux fichiers !
            % UpdateZSlice(app, z_value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.BiocadTab = uitab(app.MainTabGroup);
            app.BiocadTab.Title = 'Biocad';
            app.ToolsPanel_Menu = uipanel(app.BiocadTab);
            app.BiocadLogo = uiimage(app.BiocadTab);
            app.BiocadLogo.ImageSource = fullfile(pathToMLAPP, 'Assets', 'biocad.png');
            app.AboutButton = uibutton(app.BiocadTab, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.StoreDirectoryButtonGroup = uibuttongroup(app.BiocadTab);
            app.StoreDirectoryButtonGroup.Title = 'STORE DIRECTORY';
            app.BrowseButton = uibutton(app.StoreDirectoryButtonGroup, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FilePathLabel = uilabel(app.StoreDirectoryButtonGroup);
            app.FilePathLabel.Text = 'Select your store directory...';
            app.LoadingPanel = uipanel(app.BiocadTab);
            app.LoadingLabel = uilabel(app.LoadingPanel);
            app.LoadingLabel.Text = 'Loading...';
            app.FileTab = uitab(app.MainTabGroup);
            app.FileTab.Title = 'File';
            app.ToolsPanel_Parameters = uipanel(app.FileTab);
            app.SystemPanel = uipanel(app.ToolsPanel_Parameters);
            app.DimensionLabel = uilabel(app.SystemPanel);
            app.DimensionLabel.Text = 'Dimension :';
            app.EntryDimensionLabel = uilabel(app.SystemPanel);
            app.EntryDimensionLabel.Text = '';
            app.ElectrodesLabel = uilabel(app.SystemPanel);
            app.ElectrodesLabel.Text = 'Electrodes :';
            app.EntryProbeLabel = uilabel(app.SystemPanel);
            app.EntryProbeLabel.Text = '';
            app.MeasurementConfigurationLabel = uilabel(app.SystemPanel);
            app.MeasurementConfigurationLabel.Text = 'Measurement Configuration';
            app.LoadGeo = uipanel(app.ToolsPanel_Parameters);
            app.LoadGeometriesLabel = uilabel(app.LoadGeo);
            app.LoadGeometriesLabel.Text = 'Load Geometries';
            app.ImportButton_MicroSystem = uibutton(app.LoadGeo, 'push');
            app.ImportButton_MicroSystem.ButtonPushedFcn = createCallbackFcn(app, @LoadMPHButtonPushed, true);
            app.ImportButton_MicroSystem.Text = 'Import mph';
            app.ClearButton_MicroSystem = uibutton(app.LoadGeo, 'push');
            app.ClearButton_MicroSystem.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_MicroSystemPushed, true);
            app.ClearButton_MicroSystem.Text = 'Clear';
            app.ImportButton_Biocells = uibutton(app.LoadGeo, 'push');
            app.ImportButton_Biocells.ButtonPushedFcn = createCallbackFcn(app, @LoadIMSFolderButtonPushed, true);
            app.ImportButton_Biocells.Text = 'Import IMS';
            app.ClearButton_Biocells = uibutton(app.LoadGeo, 'push');
            app.ClearButton_Biocells.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_BiocellsPushed, true);
            app.ClearButton_Biocells.Text = 'Clear';
            app.LoadStudyPanel = uipanel(app.ToolsPanel_Parameters);
            app.LoadStudyPanel.SizeChangedFcn = createCallbackFcn(app, @LoadMatlabFileButton_ParametersPushed, true);
            app.LoadStudyLabel = uilabel(app.LoadStudyPanel);
            app.LoadStudyLabel.Text = 'Load Study';
            app.LoadMatlabFileButton_Parameters = uibutton(app.LoadStudyPanel, 'push');
            app.LoadMatlabFileButton_Parameters.Icon = fullfile(pathToMLAPP, 'Assets', 'icons8-dossier-48.png');
            app.LoadMatlabFileButton_Parameters.Text = '';
            app.SaveStudyPanel = uipanel(app.ToolsPanel_Parameters);
            app.SaveStudyLabel = uilabel(app.SaveStudyPanel);
            app.SaveStudyLabel.Text = 'Save Study';
            app.SaveButton = uibutton(app.SaveStudyPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'Assets', 'saveicon.png');
            app.SaveButton.Tooltip = {'Save parameters'};
            app.SaveButton.Text = '';
            app.CellularTissuePanel = uipanel(app.FileTab);
            app.CellularTissuePanel.Title = 'Cellular Tissue';
            app.UIAxes_Biocells = uiaxes(app.CellularTissuePanel);
            title(app.UIAxes_Biocells, 'Preview')
            xlabel(app.UIAxes_Biocells, 'X')
            ylabel(app.UIAxes_Biocells, 'Y')
            zlabel(app.UIAxes_Biocells, 'Z')
            app.UIAxes_Biocells.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.FileInfoEntryLabel_Biocells = uilabel(app.CellularTissuePanel);
            app.FileInfoEntryLabel_Biocells.Text = '';
            app.FileInfoLabel_Biocells = uilabel(app.CellularTissuePanel);
            app.FileInfoLabel_Biocells.Text = 'File Name :';
            app.AddcellstomodelButton = uibutton(app.CellularTissuePanel, 'push');
            app.AddcellstomodelButton.ButtonPushedFcn = createCallbackFcn(app, @AddcellstomodelButtonPushed, true);
            app.AddcellstomodelButton.Tooltip = {'May add imported IMARIS cells to the current imported COMSOL model, if no IMARIS cell has already been added to it.'};
            app.AddcellstomodelButton.Text = 'Add cells to model';
            app.SaveCopyCheckBox = uicheckbox(app.CellularTissuePanel);
            app.SaveCopyCheckBox.Tooltip = {'Copy the current imported COMSOL .mph file to which are added the imported IMARIS cells.'};
            app.SaveCopyCheckBox.Text = 'Save to .mph copy';
            app.DistanceToReferentialEditField_X = uieditfield(app.CellularTissuePanel, 'numeric');
            app.DistanceToReferentialEditField_Y = uieditfield(app.CellularTissuePanel, 'numeric');
            app.DistanceToReferentialEditField_Z = uieditfield(app.CellularTissuePanel, 'numeric');
            app.DistanceToReferentialLabel = uilabel(app.CellularTissuePanel);
            app.DistanceToReferentialLabel.Text = 'Distance (x,y,z) to model referential from Imaris origin : ';
            app.MicroSystemPanel = uipanel(app.FileTab);
            app.MicroSystemPanel.Title = 'Microsystem';
            app.UIAxes_MicroSystem = uiaxes(app.MicroSystemPanel);
            title(app.UIAxes_MicroSystem, 'Preview')
            xlabel(app.UIAxes_MicroSystem, 'X')
            ylabel(app.UIAxes_MicroSystem, 'Y')
            zlabel(app.UIAxes_MicroSystem, 'Z')
            app.UIAxes_MicroSystem.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.FileInfoEntryLabel_MicroSystem = uilabel(app.MicroSystemPanel);
            app.FileInfoEntryLabel_MicroSystem.Text = '';
            app.ElectrodeRolePanel = uipanel(app.MicroSystemPanel);
            app.ElectrodeRolePanel.Title = 'Electrode role in model :';
            app.V0DropDownLabel_E = uilabel(app.ElectrodeRolePanel);
            app.V0DropDownLabel_E.Text = 'V0';
            app.V0DropDown_E = uidropdown(app.ElectrodeRolePanel);
            app.V0DropDown_E.Items = {'1', '2', '3', '4'};
            app.V0DropDown_E.Value = '1';
            app.VDDDropDownLabel_E = uilabel(app.ElectrodeRolePanel);
            app.VDDDropDownLabel_E.Text = 'VDD';
            app.VDDropDown_E = uidropdown(app.ElectrodeRolePanel);
            app.VDDropDown_E.Items = {'1', '2', '3', '4'};
            app.VDDropDown_E.Value = '2';
            app.CurrentPositiveDropDownLabel_E = uilabel(app.ElectrodeRolePanel);
            app.CurrentPositiveDropDownLabel_E.Text = 'Current +';
            app.CurrentPositiveDropDown_E = uidropdown(app.ElectrodeRolePanel);
            app.CurrentPositiveDropDown_E.Items = {'1', '2', '3', '4'};
            app.CurrentPositiveDropDown_E.Value = '2';
            app.CurrentNegativeDropDownLabel_E = uilabel(app.ElectrodeRolePanel);
            app.CurrentNegativeDropDownLabel_E.Text = 'Current -';
            app.CurrentNegativeDropDown_E = uidropdown(app.ElectrodeRolePanel);
            app.CurrentNegativeDropDown_E.Items = {'1', '2', '3', '4'};
            app.CurrentNegativeDropDown_E.Value = '1';
            app.FileInfoLabel_MicroSystem = uilabel(app.MicroSystemPanel);
            app.FileInfoLabel_MicroSystem.Text = 'File Name :';
            app.GEOMESTab = uitab(app.MainTabGroup);
            app.GEOMESTab.Title = 'Geometry & Mesh';
            app.UIAxesGeometry_GEOMESResult = uiaxes(app.GEOMESTab);
            title(app.UIAxesGeometry_GEOMESResult, 'Model geometry')
            xlabel(app.UIAxesGeometry_GEOMESResult, 'X')
            ylabel(app.UIAxesGeometry_GEOMESResult, 'Y')
            zlabel(app.UIAxesGeometry_GEOMESResult, 'Z')
            app.UIAxesMesh_GEOMESResult = uiaxes(app.GEOMESTab);
            title(app.UIAxesMesh_GEOMESResult, 'Model mesh')
            xlabel(app.UIAxesMesh_GEOMESResult, 'X')
            ylabel(app.UIAxesMesh_GEOMESResult, 'Y')
            zlabel(app.UIAxesMesh_GEOMESResult, 'Z')
            app.ToolsPanel_Plot_GEOMES = uipanel(app.GEOMESTab);
            app.MeshdensitySliderLabel = uilabel(app.ToolsPanel_Plot_GEOMES);
            app.MeshdensitySliderLabel.Text = 'Mesh density';
            app.MeshdensitySlider = uislider(app.ToolsPanel_Plot_GEOMES);
            app.MeshdensitySlider.Limits = [1 9];
            app.MeshdensitySlider.MajorTicks = [1 2 3 4 5 6 7 8 9];
            app.MeshdensitySlider.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9];
            app.MeshdensitySlider.Value = 5;
            app.SaveAllFiguresButton_GEOMES = uibutton(app.ToolsPanel_Plot_GEOMES, 'push');
            app.SaveAllFiguresButton_GEOMES.ButtonPushedFcn = createCallbackFcn(app, @SaveAllFiguresButton_SPICEPushed, true);
            app.SaveAllFiguresButton_GEOMES.Enable = 'off';
            app.SaveAllFiguresButton_GEOMES.Text = 'Save All Figures';
            app.MeshnumberEditFieldLabel = uilabel(app.ToolsPanel_Plot_GEOMES);
            app.MeshnumberEditFieldLabel.Text = 'Mesh number';
            app.MeshnumberEditField = uieditfield(app.ToolsPanel_Plot_GEOMES, 'numeric');
            app.MeshnumberEditField.Value = 3;
            app.MaterialsTab = uitab(app.MainTabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.ToolsPanel_Plot_MATERIAL = uipanel(app.MaterialsTab);
            app.FILELabel_Plot_MATERIAL = uilabel(app.ToolsPanel_Plot_MATERIAL);
            app.FILELabel_Plot_MATERIAL.Text = 'The microsystem is made of  top and bottom parts with either 2 or 4 electrodes. In case of 2-points  configuration, a double layer modifiies the impedance measurement at low frequency. Two culture mediums can be modelled and two cell types can  be integrated. The electric cell model includes the cytoplasma, the membrane and the double layer around the membrane.';
            app.MicrosystemPanel = uipanel(app.MaterialsTab);
            app.MicrosystemPanel.Title = 'Microsystem';
            app.PDMSLabel = uilabel(app.MicrosystemPanel);
            app.PDMSLabel.Text = 'PDMS';
            app.PDMS_conduct_Label = uilabel(app.MicrosystemPanel);
            app.PDMS_conduct_Label.Text = 'Electric conductivity';
            app.PDMS_conduct = uieditfield(app.MicrosystemPanel, 'numeric');
            app.PDMS_conduct.Value = 1e-12;
            app.PDMS_conduct_unit = uidropdown(app.MicrosystemPanel);
            app.PDMS_conduct_unit.Items = {'S/m'};
            app.PDMS_conduct_unit.Value = 'S/m';
            app.PDMS_permit_Label = uilabel(app.MicrosystemPanel);
            app.PDMS_permit_Label.Text = 'Relative permittivity';
            app.PDMS_permit = uieditfield(app.MicrosystemPanel, 'numeric');
            app.PDMS_permit.Value = 2.75;
            app.PDMS_unit = uidropdown(app.MicrosystemPanel);
            app.PDMS_unit.Items = {'-'};
            app.PDMS_unit.Value = '-';
            app.Plastic_CapLabel = uilabel(app.MicrosystemPanel);
            app.Plastic_CapLabel.Text = 'Plastic_Cap';
            app.Plastic_Cap_conduct_unit = uidropdown(app.MicrosystemPanel);
            app.Plastic_Cap_conduct_unit.Items = {'S/m'};
            app.Plastic_Cap_conduct_unit.Value = 'S/m';
            app.Plastic_Cap_permit_unit = uidropdown(app.MicrosystemPanel);
            app.Plastic_Cap_permit_unit.Items = {'-'};
            app.Plastic_Cap_permit_unit.Value = '-';
            app.Plastic_Cap_conduct_Label = uilabel(app.MicrosystemPanel);
            app.Plastic_Cap_conduct_Label.Text = 'Electric conductivity';
            app.Plastic_Cap_conduct = uieditfield(app.MicrosystemPanel, 'numeric');
            app.Plastic_Cap_conduct.Value = 1e-12;
            app.Plastic_Cap_permit_Label = uilabel(app.MicrosystemPanel);
            app.Plastic_Cap_permit_Label.Text = 'Relative permittivity';
            app.Plastic_Cap_permit = uieditfield(app.MicrosystemPanel, 'numeric');
            app.Plastic_Cap_permit.Value = 4.2;
            app.GlassLabel = uilabel(app.MicrosystemPanel);
            app.GlassLabel.Text = 'Glass';
            app.Glass_conduct_Label = uilabel(app.MicrosystemPanel);
            app.Glass_conduct_Label.Text = 'Electric conductivity';
            app.Glass_conduct = uieditfield(app.MicrosystemPanel, 'numeric');
            app.Glass_conduct.Value = 1e-12;
            app.Glass_conduct_unit = uidropdown(app.MicrosystemPanel);
            app.Glass_conduct_unit.Items = {'S/m'};
            app.Glass_conduct_unit.Value = 'S/m';
            app.Glass_permit_Label = uilabel(app.MicrosystemPanel);
            app.Glass_permit_Label.Text = 'Relative permittivity';
            app.Glass_permit = uieditfield(app.MicrosystemPanel, 'numeric');
            app.Glass_permit.Value = 4.2;
            app.Glass_unit = uidropdown(app.MicrosystemPanel);
            app.Glass_unit.Items = {'-'};
            app.Glass_unit.Value = '-';
            app.ElectrodesPanel = uipanel(app.MaterialsTab);
            app.ElectrodesPanel.Title = 'Electrodes';
            app.GoldLabel = uilabel(app.ElectrodesPanel);
            app.GoldLabel.Text = 'Gold';
            app.Gold_conduct_Label = uilabel(app.ElectrodesPanel);
            app.Gold_conduct_Label.Text = 'Electric conductivity';
            app.Gold_conduct = uieditfield(app.ElectrodesPanel, 'numeric');
            app.Gold_conduct.Value = 100000;
            app.Gold_conduct_unit = uidropdown(app.ElectrodesPanel);
            app.Gold_conduct_unit.Items = {'S/m'};
            app.Gold_conduct_unit.Value = 'S/m';
            app.Gold_permit_Label = uilabel(app.ElectrodesPanel);
            app.Gold_permit_Label.Text = 'Relative permittivity';
            app.Gold_permit = uieditfield(app.ElectrodesPanel, 'numeric');
            app.Gold_permit.Value = 1;
            app.Gold_unit = uidropdown(app.ElectrodesPanel);
            app.Gold_unit.Items = {'-'};
            app.Gold_unit.Value = '-';
            app.DoubleLayerLabel = uilabel(app.ElectrodesPanel);
            app.DoubleLayerLabel.Text = 'Double Layer';
            app.DoubleLayer_conduct_Label = uilabel(app.ElectrodesPanel);
            app.DoubleLayer_conduct_Label.Text = 'Electric conductivity';
            app.DoubleLayer_conduct = uieditfield(app.ElectrodesPanel, 'numeric');
            app.DoubleLayer_conduct.Value = 100000;
            app.DoubleLayer_conduct_unit = uidropdown(app.ElectrodesPanel);
            app.DoubleLayer_conduct_unit.Items = {'S/m'};
            app.DoubleLayer_conduct_unit.Value = 'S/m';
            app.DoubleLayer_permit_Label = uilabel(app.ElectrodesPanel);
            app.DoubleLayer_permit_Label.Text = 'Relative permittivity';
            app.DoubleLayer_permit = uieditfield(app.ElectrodesPanel, 'numeric');
            app.DoubleLayer_permit.Value = 1;
            app.DoubleLayer_unit = uidropdown(app.ElectrodesPanel);
            app.DoubleLayer_unit.Items = {'-'};
            app.DoubleLayer_unit.Value = '-';
            app.CultureMediumPanel = uipanel(app.MaterialsTab);
            app.CultureMediumPanel.Title = 'Culture Medium';
            app.CM1Label = uilabel(app.CultureMediumPanel);
            app.CM1Label.Text = 'CM1';
            app.CM1_conduct_Label = uilabel(app.CultureMediumPanel);
            app.CM1_conduct_Label.Text = 'Electric conductivity';
            app.CM1_conduct = uieditfield(app.CultureMediumPanel, 'numeric');
            app.CM1_conduct.Value = 1.5;
            app.CM1_conduct_unit = uidropdown(app.CultureMediumPanel);
            app.CM1_conduct_unit.Items = {'S/m'};
            app.CM1_conduct_unit.Value = 'S/m';
            app.CM1_permit_Label = uilabel(app.CultureMediumPanel);
            app.CM1_permit_Label.Text = 'Relative permittivity';
            app.CM1_permit = uieditfield(app.CultureMediumPanel, 'numeric');
            app.CM1_permit.Value = 80;
            app.CM1_unit = uidropdown(app.CultureMediumPanel);
            app.CM1_unit.Items = {'-'};
            app.CM1_unit.Value = '-';
            app.CM2Label = uilabel(app.CultureMediumPanel);
            app.CM2Label.Text = 'CM2';
            app.CM2_conduct_Label = uilabel(app.CultureMediumPanel);
            app.CM2_conduct_Label.Text = 'Electric conductivity';
            app.CM2_conduct = uieditfield(app.CultureMediumPanel, 'numeric');
            app.CM2_conduct.Value = 0.01;
            app.CM2_conduct_unit = uidropdown(app.CultureMediumPanel);
            app.CM2_conduct_unit.Items = {'S/m'};
            app.CM2_conduct_unit.Value = 'S/m';
            app.CM2_permit_Label = uilabel(app.CultureMediumPanel);
            app.CM2_permit_Label.Text = 'Relative permittivity';
            app.CM2_permit = uieditfield(app.CultureMediumPanel, 'numeric');
            app.CM2_permit.Value = 10;
            app.CM2_unit = uidropdown(app.CultureMediumPanel);
            app.CM2_unit.Items = {'-'};
            app.CM2_unit.Value = '-';
            app.BiologicalCellsPanel = uipanel(app.MaterialsTab);
            app.BiologicalCellsPanel.Title = 'Biological Cells';
            app.BC1_CytoLabel = uilabel(app.BiologicalCellsPanel);
            app.BC1_CytoLabel.Text = 'BC1_Cyto';
            app.BC1_Cyto_conduct_Label = uilabel(app.BiologicalCellsPanel);
            app.BC1_Cyto_conduct_Label.Text = 'Electric conductivity';
            app.BC1_Cyto_conduct = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC1_Cyto_conduct.Value = 0.0001;
            app.BC1_Cyto_conduct_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC1_Cyto_conduct_unit.Items = {'S/m'};
            app.BC1_Cyto_conduct_unit.Value = 'S/m';
            app.BC1_Cyto_permit_Label = uilabel(app.BiologicalCellsPanel);
            app.BC1_Cyto_permit_Label.Text = 'Relative permittivity';
            app.BC1_Cyto_permit = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC1_Cyto_permit.Value = 10;
            app.BC1_Cyto_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC1_Cyto_unit.Items = {'-'};
            app.BC1_Cyto_unit.Value = '-';
            app.BC2_CytoLabel = uilabel(app.BiologicalCellsPanel);
            app.BC2_CytoLabel.Text = 'BC2_Cyto';
            app.BC2_Cyto_conduct_Label = uilabel(app.BiologicalCellsPanel);
            app.BC2_Cyto_conduct_Label.Text = 'Electric conductivity';
            app.BC2_Cyto_conduct = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC2_Cyto_conduct.Value = 0.01;
            app.BC2_Cyto_conduct_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC2_Cyto_conduct_unit.Items = {'S/m'};
            app.BC2_Cyto_conduct_unit.Value = 'S/m';
            app.BC2_Cyto_permit_Label = uilabel(app.BiologicalCellsPanel);
            app.BC2_Cyto_permit_Label.Text = 'Relative permittivity';
            app.BC2_Cyto_permit = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC2_Cyto_permit.Value = 10;
            app.BC2_Cyto_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC2_Cyto_unit.Items = {'-'};
            app.BC2_Cyto_unit.Value = '-';
            app.BC1_MemLabel = uilabel(app.BiologicalCellsPanel);
            app.BC1_MemLabel.Text = 'BC1_Mem';
            app.BC1_Mem_conduct_Label = uilabel(app.BiologicalCellsPanel);
            app.BC1_Mem_conduct_Label.Text = 'Electric conductivity';
            app.BC1_Mem_conduct = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC1_Mem_conduct.Value = 0.0001;
            app.BC1_Mem_conduct_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC1_Mem_conduct_unit.Items = {'S/m'};
            app.BC1_Mem_conduct_unit.Value = 'S/m';
            app.BC1_Mem_permit_Label = uilabel(app.BiologicalCellsPanel);
            app.BC1_Mem_permit_Label.Text = 'Relative permittivity';
            app.BC1_Mem_permit = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC1_Mem_permit.Value = 10;
            app.BC1_Mem_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC1_Mem_unit.Items = {'-'};
            app.BC1_Mem_unit.Value = '-';
            app.BC2_MemLabel = uilabel(app.BiologicalCellsPanel);
            app.BC2_MemLabel.Text = 'BC2_Mem';
            app.BC2_Mem_conduct_Label = uilabel(app.BiologicalCellsPanel);
            app.BC2_Mem_conduct_Label.Text = 'Electric conductivity';
            app.BC2_Mem_conduct = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC2_Mem_conduct.Value = 0.0001;
            app.BC2_Mem_conduct_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC2_Mem_conduct_unit.Items = {'S/m'};
            app.BC2_Mem_conduct_unit.Value = 'S/m';
            app.BC2_Mem_permit_Label = uilabel(app.BiologicalCellsPanel);
            app.BC2_Mem_permit_Label.Text = 'Relative permittivity';
            app.BC2_Mem_permit = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC2_Mem_permit.Value = 10;
            app.BC2_Mem_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC2_Mem_unit.Items = {'-'};
            app.BC2_Mem_unit.Value = '-';
            app.BC1_DLLabel = uilabel(app.BiologicalCellsPanel);
            app.BC1_DLLabel.Text = 'BC1_DL';
            app.BC1_DL_conduct_Label = uilabel(app.BiologicalCellsPanel);
            app.BC1_DL_conduct_Label.Text = 'Electric conductivity';
            app.BC1_DL_conduct = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC1_DL_conduct.Value = 0.0001;
            app.BC1_DL_conduct_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC1_DL_conduct_unit.Items = {'S/m'};
            app.BC1_DL_conduct_unit.Value = 'S/m';
            app.BC1_DL_permit_Label = uilabel(app.BiologicalCellsPanel);
            app.BC1_DL_permit_Label.Text = 'Relative permittivity';
            app.BC1_DL_permit = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC1_DL_permit.Value = 10;
            app.BC1_DL_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC1_DL_unit.Items = {'-'};
            app.BC1_DL_unit.Value = '-';
            app.BC2_DLLabel = uilabel(app.BiologicalCellsPanel);
            app.BC2_DLLabel.Text = 'BC2_DL';
            app.BC2_DL_conduct_Label = uilabel(app.BiologicalCellsPanel);
            app.BC2_DL_conduct_Label.Text = 'Electric conductivity';
            app.BC2_DL_conduct = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC2_DL_conduct.Value = 0.0001;
            app.BC2_DL_conduct_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC2_DL_conduct_unit.Items = {'S/m'};
            app.BC2_DL_conduct_unit.Value = 'S/m';
            app.BC2_DL_permit_Label = uilabel(app.BiologicalCellsPanel);
            app.BC2_DL_permit_Label.Text = 'Relative permittivity';
            app.BC2_DL_permit = uieditfield(app.BiologicalCellsPanel, 'numeric');
            app.BC2_DL_permit.Value = 10;
            app.BC2_DL_unit = uidropdown(app.BiologicalCellsPanel);
            app.BC2_DL_unit.Items = {'-'};
            app.BC2_DL_unit.Value = '-';
            app.STUDYTab = uitab(app.MainTabGroup);
            app.STUDYTab.Title = 'Study';
            app.ToolsPanel_Plot_STUDY = uipanel(app.STUDYTab);
            app.TransportLatticeListBox = uilistbox(app.ToolsPanel_Plot_STUDY);
            app.TransportLatticeListBox.Items = {'SPICE'};
            app.TransportLatticeListBox.Tooltip = {'This study is always done.'};
            app.TransportLatticeListBox.Value = 'SPICE';
            app.StudiesListBox = uilistbox(app.ToolsPanel_Plot_STUDY);
            app.StudiesListBox.Items = {'Finite Elements', 'Analytical'};
            app.StudiesListBox.Multiselect = 'on';
            app.StudiesListBox.Tooltip = {'Hold Ctrl to select multiple options'};
            app.StudiesListBox.Value = {'Finite Elements'};
            app.RunStudy = uipanel(app.ToolsPanel_Plot_STUDY);
            app.RunStudyLabel = uilabel(app.RunStudy);
            app.RunStudyLabel.Text = 'Run Study';
            app.RunButton = uibutton(app.RunStudy, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PreviewallButton = uibutton(app.RunStudy, 'push');
            app.PreviewallButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewallButtonPushed, true);
            app.PreviewallButton.Text = 'Preview all';
            app.LogCheckBox = uicheckbox(app.RunStudy);
            app.LogCheckBox.Tooltip = {'Activate loging.'};
            app.LogCheckBox.Text = ' Log';
            app.FigCheckBox = uicheckbox(app.RunStudy);
            app.FigCheckBox.Tooltip = {'Activate loging.'};
            app.FigCheckBox.Text = 'Fig';
            app.StudynumberEditFieldLabel = uilabel(app.ToolsPanel_Plot_STUDY);
            app.StudynumberEditFieldLabel.Text = 'Study number';
            app.StudynumberEditField = uieditfield(app.ToolsPanel_Plot_STUDY, 'numeric');
            app.StudynumberEditField.Value = 3;
            app.TerminalnumberEditFieldLabel = uilabel(app.ToolsPanel_Plot_STUDY);
            app.TerminalnumberEditFieldLabel.Text = 'Terminal number';
            app.TerminalnumberEditField = uieditfield(app.ToolsPanel_Plot_STUDY, 'numeric');
            app.TerminalnumberEditField.Value = 5;
            app.FrequencyDomainPanel = uipanel(app.STUDYTab);
            app.FrequencyDomainPanel.Title = 'Frequency Domain';
            app.FrequencyLabel = uilabel(app.FrequencyDomainPanel);
            app.FrequencyLabel.Text = 'Frequency';
            app.minFrequencyEditFieldLabel = uilabel(app.FrequencyDomainPanel);
            app.minFrequencyEditFieldLabel.Text = 'min';
            app.minFrequencyEditField = uieditfield(app.FrequencyDomainPanel, 'numeric');
            app.minFrequencyEditField.Value = 1000;
            app.minFrequencyUnitDropDown = uidropdown(app.FrequencyDomainPanel);
            app.minFrequencyUnitDropDown.Items = {'Hz', 'kHz', 'MHz'};
            app.minFrequencyUnitDropDown.Value = 'Hz';
            app.intFrequencyEditFieldLabel = uilabel(app.FrequencyDomainPanel);
            app.intFrequencyEditFieldLabel.Text = 'int';
            app.intFrequencyEditField = uieditfield(app.FrequencyDomainPanel, 'numeric');
            app.intFrequencyEditField.Value = 100000;
            app.intFrequencyUnitDropDown = uidropdown(app.FrequencyDomainPanel);
            app.intFrequencyUnitDropDown.Items = {'Hz', 'kHz', 'MHz'};
            app.intFrequencyUnitDropDown.Value = 'Hz';
            app.PointsperdecadeEditFieldLabel = uilabel(app.FrequencyDomainPanel);
            app.PointsperdecadeEditFieldLabel.Text = 'Points per decade';
            app.PointsperdecadeEditField = uieditfield(app.FrequencyDomainPanel, 'numeric');
            app.PointsperdecadeEditField.Value = 9;
            app.maxFrequencyUnitDropDown = uidropdown(app.FrequencyDomainPanel);
            app.maxFrequencyUnitDropDown.Items = {'Hz', 'kHz', 'MHz'};
            app.maxFrequencyUnitDropDown.Value = 'Hz';
            app.maxFrequencyEditFieldLabel = uilabel(app.FrequencyDomainPanel);
            app.maxFrequencyEditFieldLabel.Text = 'max';
            app.maxFrequencyEditField = uieditfield(app.FrequencyDomainPanel, 'numeric');
            app.maxFrequencyEditField.Value = 10000000000;
            app.BoundaryConditionsPanel = uipanel(app.STUDYTab);
            app.BoundaryConditionsPanel.Title = 'Boundary Conditions';
            app.VoltageDropEditFieldLabel = uilabel(app.BoundaryConditionsPanel);
            app.VoltageDropEditFieldLabel.Text = ' Voltage Drop';
            app.VoltageDropEditField = uieditfield(app.BoundaryConditionsPanel, 'numeric');
            app.VoltageDropEditField.Value = 25;
            app.VoltageUnitDropDown = uidropdown(app.BoundaryConditionsPanel);
            app.VoltageUnitDropDown.Items = {'V', 'mV', 'µV', 'nV'};
            app.VoltageUnitDropDown.Value = 'mV';
            app.SPICETab = uitab(app.MainTabGroup);
            app.SPICETab.Title = 'SPICE';
            app.ToolsPanel_Plot_SPICE = uipanel(app.SPICETab);
            app.SaveAllFiguresButton_SPICE = uibutton(app.ToolsPanel_Plot_SPICE, 'push');
            app.SaveAllFiguresButton_SPICE.ButtonPushedFcn = createCallbackFcn(app, @SaveAllFiguresButton_SPICEPushed, true);
            app.SaveAllFiguresButton_SPICE.Enable = 'off';
            app.SaveAllFiguresButton_SPICE.Text = 'Save All Figures';
            app.FILELabel_Plot_SPICE = uilabel(app.ToolsPanel_Plot_SPICE);
            app.FILELabel_Plot_SPICE.Text = 'FILE';
            app.TabGroup_SPICE = uitabgroup(app.SPICETab);
            app.Bode_SPICETab = uitab(app.TabGroup_SPICE);
            app.Bode_SPICETab.Title = 'Bode_SPICE';
            app.UIAxesMagnitude_Bode_SPICE = uiaxes(app.Bode_SPICETab);
            title(app.UIAxesMagnitude_Bode_SPICE, '[SPICE] Bode Diagram : Magnitude')
            xlabel(app.UIAxesMagnitude_Bode_SPICE, 'Frequency')
            ylabel(app.UIAxesMagnitude_Bode_SPICE, 'Magnitude (dB)')
            zlabel(app.UIAxesMagnitude_Bode_SPICE, 'Z')
            app.UIAxesMagnitude_Bode_SPICE.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxesMagnitude_Bode_SPICE.Tag = '_Spice_Bode_Magnitude';
            app.UIAxesPhase_Bode_SPICE = uiaxes(app.Bode_SPICETab);
            title(app.UIAxesPhase_Bode_SPICE, '[SPICE] Bode Diagram : Phase')
            xlabel(app.UIAxesPhase_Bode_SPICE, 'Frequency')
            ylabel(app.UIAxesPhase_Bode_SPICE, 'Phase (degrees)')
            zlabel(app.UIAxesPhase_Bode_SPICE, 'Z')
            app.UIAxesPhase_Bode_SPICE.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxesPhase_Bode_SPICE.Tag = '_Spice_Bode_Phase';
            app.Nyquist_SPICETab = uitab(app.TabGroup_SPICE);
            app.Nyquist_SPICETab.Title = 'Nyquist_SPICE';
            app.UIAxes_Nyquist_SPICE = uiaxes(app.Nyquist_SPICETab);
            title(app.UIAxes_Nyquist_SPICE, '[SPICE] Nyquist Plot for Electrical Impedance')
            xlabel(app.UIAxes_Nyquist_SPICE, 'Re(|Z|)   (Ohms)')
            ylabel(app.UIAxes_Nyquist_SPICE, 'Im(|Z|)   (Ohms)')
            zlabel(app.UIAxes_Nyquist_SPICE, 'Z')
            app.UIAxes_Nyquist_SPICE.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_Nyquist_SPICE.Tag = '_Spice_Nyquist';
            app.VfminMapSPICETab = uitab(app.TabGroup_SPICE);
            app.VfminMapSPICETab.Title = 'V(fmin) Map SPICE';
            app.UIAxes_PotentialMapfmin_SPICE = uiaxes(app.VfminMapSPICETab);
            title(app.UIAxes_PotentialMapfmin_SPICE, '[SPICE] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfmin_SPICE, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfmin_SPICE, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfmin_SPICE, 'Potential Difference   (V)')
            app.UIAxes_PotentialMapfmin_SPICE.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_PotentialMapfmin_SPICE.Tag = '_Spice_Potential_Map_fmin';
            app.SliderLabel = uilabel(app.VfminMapSPICETab);
            app.SliderLabel.Text = 'Slider';
            app.ZSliceSlider = uislider(app.VfminMapSPICETab);
            app.ZSliceSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliceSliderValueChanged, true);
            app.VfintMapSPICETab = uitab(app.TabGroup_SPICE);
            app.VfintMapSPICETab.Title = 'V(fint) Map SPICE';
            app.UIAxes_PotentialMapfint_SPICE = uiaxes(app.VfintMapSPICETab);
            title(app.UIAxes_PotentialMapfint_SPICE, '[SPICE] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfint_SPICE, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfint_SPICE, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfint_SPICE, 'Potential Difference   (V)')
            app.UIAxes_PotentialMapfint_SPICE.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_PotentialMapfint_SPICE.Tag = '_Spice_Potential_Map_fmax';
            app.VfmaxMapSPICETab = uitab(app.TabGroup_SPICE);
            app.VfmaxMapSPICETab.Title = 'V(fmax) Map SPICE';
            app.UIAxes_PotentialMapfmax_SPICE = uiaxes(app.VfmaxMapSPICETab);
            title(app.UIAxes_PotentialMapfmax_SPICE, '[SPICE] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfmax_SPICE, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfmax_SPICE, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfmax_SPICE, 'Potential Difference   (V)')
            app.FEMTab = uitab(app.MainTabGroup);
            app.FEMTab.Title = 'FEM';
            app.ToolsPanel_Plot_FEM = uipanel(app.FEMTab);
            app.SaveAllFiguresButton_FEM = uibutton(app.ToolsPanel_Plot_FEM, 'push');
            app.SaveAllFiguresButton_FEM.Enable = 'off';
            app.SaveAllFiguresButton_FEM.Text = 'Save All Figures';
            app.FILELabel_Plot_FEM = uilabel(app.ToolsPanel_Plot_FEM);
            app.FILELabel_Plot_FEM.Text = 'FILE';
            app.TabGroup_FEM = uitabgroup(app.FEMTab);
            app.Bode_FEMTab = uitab(app.TabGroup_FEM);
            app.Bode_FEMTab.Title = 'Bode_FEM';
            app.UIAxesMagnitude_Bode_FEM = uiaxes(app.Bode_FEMTab);
            title(app.UIAxesMagnitude_Bode_FEM, '[FEM] Bode Diagram : Magnitude')
            xlabel(app.UIAxesMagnitude_Bode_FEM, 'Frequency')
            ylabel(app.UIAxesMagnitude_Bode_FEM, 'Magnitude (dB)')
            zlabel(app.UIAxesMagnitude_Bode_FEM, 'Z')
            app.UIAxesPhase_Bode_FEM = uiaxes(app.Bode_FEMTab);
            title(app.UIAxesPhase_Bode_FEM, '[FEM] Bode Diagram : Phase')
            xlabel(app.UIAxesPhase_Bode_FEM, 'Frequency')
            ylabel(app.UIAxesPhase_Bode_FEM, 'Phase (degrees)')
            zlabel(app.UIAxesPhase_Bode_FEM, 'Z')
            app.Nyquist_FEMTab = uitab(app.TabGroup_FEM);
            app.Nyquist_FEMTab.Title = 'Nyquist_FEM';
            app.UIAxes_Nyquist_FEM = uiaxes(app.Nyquist_FEMTab);
            title(app.UIAxes_Nyquist_FEM, '[FEM] Nyquist Plot for Electrical Impedance')
            xlabel(app.UIAxes_Nyquist_FEM, 'Re(|Z|)   (Ohms)')
            ylabel(app.UIAxes_Nyquist_FEM, 'Im(|Z|)   (Ohms)')
            zlabel(app.UIAxes_Nyquist_FEM, 'Z')
            app.VfminMapFEMTab = uitab(app.TabGroup_FEM);
            app.VfminMapFEMTab.Title = 'V(fmin) Map FEM';
            app.UIAxes_PotentialMapfmin_FEM = uiaxes(app.VfminMapFEMTab);
            title(app.UIAxes_PotentialMapfmin_FEM, '[FEM] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfmin_FEM, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfmin_FEM, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfmin_FEM, 'Potential Difference   (V)')
            app.VfintMapFEMTab = uitab(app.TabGroup_FEM);
            app.VfintMapFEMTab.Title = 'V(fint) Map FEM';
            app.UIAxes_PotentialMapfint_FEM = uiaxes(app.VfintMapFEMTab);
            title(app.UIAxes_PotentialMapfint_FEM, '[FEM] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfint_FEM, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfint_FEM, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfint_FEM, 'Potential Difference   (V)')
            app.VfmaxMapFEMTab_FEM = uitab(app.TabGroup_FEM);
            app.VfmaxMapFEMTab_FEM.Title = 'V(fmax) Map FEM';
            app.UIAxes_PotentialMapfmax_FEM = uiaxes(app.VfmaxMapFEMTab_FEM);
            title(app.UIAxes_PotentialMapfmax_FEM, '[FEM] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfmax_FEM, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfmax_FEM, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfmax_FEM, 'Potential Difference   (V)')
            app.ANATab = uitab(app.MainTabGroup);
            app.ANATab.Title = 'ANA';
            app.ToolsPanel_Plot_ANA = uipanel(app.ANATab);
            app.SaveAllFiguresButton_ANA = uibutton(app.ToolsPanel_Plot_ANA, 'push');
            app.SaveAllFiguresButton_ANA.Enable = 'off';
            app.SaveAllFiguresButton_ANA.Text = 'Save All Figures';
            app.FILELabel_Plot_ANA = uilabel(app.ToolsPanel_Plot_ANA);
            app.FILELabel_Plot_ANA.Text = 'FILE';
            app.TabGroup_ANA = uitabgroup(app.ANATab);
            app.Bode_ANATab = uitab(app.TabGroup_ANA);
            app.Bode_ANATab.Title = 'Bode_ANA';
            app.UIAxesMagnitude_Bode_ANA = uiaxes(app.Bode_ANATab);
            title(app.UIAxesMagnitude_Bode_ANA, '[ANA] Bode Diagram : Magnitude')
            xlabel(app.UIAxesMagnitude_Bode_ANA, 'Frequency')
            ylabel(app.UIAxesMagnitude_Bode_ANA, 'Magnitude (dB)')
            zlabel(app.UIAxesMagnitude_Bode_ANA, 'Z')
            app.UIAxesPhase_Bode_ANA = uiaxes(app.Bode_ANATab);
            title(app.UIAxesPhase_Bode_ANA, '[ANA] Bode Diagram : Phase')
            xlabel(app.UIAxesPhase_Bode_ANA, 'Frequency')
            ylabel(app.UIAxesPhase_Bode_ANA, 'Phase (degrees)')
            zlabel(app.UIAxesPhase_Bode_ANA, 'Z')
            app.Nyquist_ANATab = uitab(app.TabGroup_ANA);
            app.Nyquist_ANATab.Title = 'Nyquist_ANA';
            app.UIAxes_Nyquist_ANA = uiaxes(app.Nyquist_ANATab);
            title(app.UIAxes_Nyquist_ANA, '[ANA] Nyquist Plot for Electrical Impedance')
            xlabel(app.UIAxes_Nyquist_ANA, 'Re(|Z|)   (Ohms)')
            ylabel(app.UIAxes_Nyquist_ANA, 'Im(|Z|)   (Ohms)')
            zlabel(app.UIAxes_Nyquist_ANA, 'Z')
            app.VfminMapANATab = uitab(app.TabGroup_ANA);
            app.VfminMapANATab.Title = 'V(fmin) Map ANA';
            app.UIAxes_PotentialMapfmin_ANA = uiaxes(app.VfminMapANATab);
            title(app.UIAxes_PotentialMapfmin_ANA, '[ANA] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfmin_ANA, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfmin_ANA, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfmin_ANA, 'Potential Difference   (V)')
            app.VfintMapANATab = uitab(app.TabGroup_ANA);
            app.VfintMapANATab.Title = 'V(fint) Map ANA';
            app.UIAxes_PotentialMapfint_ANA = uiaxes(app.VfintMapANATab);
            title(app.UIAxes_PotentialMapfint_ANA, '[ANA] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfint_ANA, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfint_ANA, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfint_ANA, 'Potential Difference   (V)')
            app.VfmaxMapFEMTab_ANA = uitab(app.TabGroup_ANA);
            app.VfmaxMapFEMTab_ANA.Title = 'V(fmax) Map ANA';
            app.UIAxes_PotentialMapfmax_ANA = uiaxes(app.VfmaxMapFEMTab_ANA);
            title(app.UIAxes_PotentialMapfmax_ANA, '[ANA] 2D Map of the Electric Potential (Volt)')
            xlabel(app.UIAxes_PotentialMapfmax_ANA, 'X coordinate   (microns)')
            ylabel(app.UIAxes_PotentialMapfmax_ANA, 'Y coordinate   (microns)')
            zlabel(app.UIAxes_PotentialMapfmax_ANA, 'Potential Difference   (V)')
            app.CopyrightsLabel = uilabel(app.UIFigure);
            app.CopyrightsLabel.Text = 'Copyrights Vincent SENEZ - Research app ';
            app.FoldernameLabel = uilabel(app.UIFigure);
            app.FoldernameLabel.Text = '';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.OpeninexternalfigureMenu = uimenu(app.ContextMenu);
            app.OpeninexternalfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @OpeninexternalfigureMenuSelected, true);
            app.OpeninexternalfigureMenu.Text = 'Open in external figure';
            % Assign app.ContextMenu
            app.UIAxes_Biocells.ContextMenu = app.ContextMenu;
            app.UIAxes_MicroSystem.ContextMenu = app.ContextMenu;
            app.UIAxesGeometry_GEOMESResult.ContextMenu = app.ContextMenu;
            app.UIAxesMesh_GEOMESResult.ContextMenu = app.ContextMenu;
            app.UIAxesMagnitude_Bode_SPICE.ContextMenu = app.ContextMenu;
            app.UIAxesPhase_Bode_SPICE.ContextMenu = app.ContextMenu;
            app.UIAxes_Nyquist_SPICE.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfmin_SPICE.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfint_SPICE.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfmax_SPICE.ContextMenu = app.ContextMenu;
            app.UIAxesMagnitude_Bode_FEM.ContextMenu = app.ContextMenu;
            app.UIAxesPhase_Bode_FEM.ContextMenu = app.ContextMenu;
            app.UIAxes_Nyquist_FEM.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfmin_FEM.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfint_FEM.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfmax_FEM.ContextMenu = app.ContextMenu;
            app.UIAxesMagnitude_Bode_ANA.ContextMenu = app.ContextMenu;
            app.UIAxesPhase_Bode_ANA.ContextMenu = app.ContextMenu;
            app.UIAxes_Nyquist_ANA.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfmin_ANA.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfint_ANA.ContextMenu = app.ContextMenu;
            app.UIAxes_PotentialMapfmax_ANA.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu2);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu2);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.MeshdensitySlider.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biocad

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end